classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PositionErrorDropDown       matlab.ui.control.DropDown
        PositionErrorDropDownLabel  matlab.ui.control.Label
        RefreshButton               matlab.ui.control.Button
        RosNodesListBox             matlab.ui.control.ListBox
        RosNodesListBoxLabel        matlab.ui.control.Label
        ConnectionStatusLabel       matlab.ui.control.Label
        LoggingStatusLabel          matlab.ui.control.Label
        LoggingOffButton            matlab.ui.control.Button
        LoggingLamp                 matlab.ui.control.Lamp
        LoggingOnButton             matlab.ui.control.Button
        RosTopicsListBox            matlab.ui.control.ListBox
        RosTopicsListBoxLabel       matlab.ui.control.Label
        DisconnectButton            matlab.ui.control.Button
        Connect                     matlab.ui.control.Lamp
        ConnectButton               matlab.ui.control.Button
        ROSMASTERURIEditField       matlab.ui.control.EditField
        ROSMASTERURIEditFieldLabel  matlab.ui.control.Label
        ORIPlot                     matlab.ui.control.UIAxes
        PosPlot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        running = 0; % Sumulation running
        %GT;
        %EKF;
        save_data = [];
        save_results = [];
        logging = 0;
        Pos = 1;
        %topic = rostopic('list');
        %Rostopic = rostopic('list');
        
    end
    
    methods (Access = private)
        
        function topics = func(app)
            topics = string(rostopic('list'));
            nodes = string(rosnode('list'));
            app.RosTopicsListBox.Items = (topics);
            app.RosNodesListBox.Items = (nodes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ROSMASTERURIEditField
        function ROSMASTERURIEditFieldValueChanged(app, event)
            value = app.ROSMASTERURIEditField.Value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            rosshutdown;
            rosinit(app.ROSMASTERURIEditField.Value);
            func(app);
            app.Connect.Color = [0 1 0];
            app.running = 1;
            app.ROSMASTERURIEditField.Enable = 'off';
            app.ConnectButton.Text = 'Connected';
            app.LoggingOnButton.Enable = 'on';
            %hold(app.PosPlot,'all');
            %X_GT_data = zeros(10,1);
            %Y_GT_data = zeros(10,1);
            %X_EKF_data = zeros(10,1);
            %Y_EKF_data = zeros(10,1);
            
            error_X = 0;
            error_Y = 0;
            Time_EKF_data = zeros(4,1);
            error_pos = zeros(4,1);
            error_ori = zeros(4,1);
            traj_x = ones(10,1).*-5.33073;
            traj_y = ones(10,1).*-3.0;
            while(app.running == 1)
                if app.Pos == 1
                    app.ORIPlot.Visible = 'on';
                    sub_ground_truth = rossubscriber('/pose');
                    %sub_ground_truth_vel = rossubscriber('/wheel_odom_2');
                    sub_ekf = rossubscriber('/odometry/filtered');
                    ground_truth_data = receive(sub_ground_truth);
                    %ground_truth_data_vel = receive(sub_ground_truth_vel);
                    ekf_data = receive(sub_ekf);
                    %X_GT_data = ground_truth_data.Header.Stamp.Sec;
                    X_GT_data = ground_truth_data.Pose.Position.X;
                    Y_GT_data = ground_truth_data.Pose.Position.Y;
                    
                    W_ORI_GT_data = ground_truth_data.Pose.Orientation.W;
                    X_ORI_GT_data = ground_truth_data.Pose.Orientation.X;
                    Y_ORI_GT_data = ground_truth_data.Pose.Orientation.Y;
                    Z_ORI_GT_data = ground_truth_data.Pose.Orientation.Z;
                    quat = [W_ORI_GT_data X_ORI_GT_data Y_ORI_GT_data Z_ORI_GT_data];
                    eulZYZ = quat2eul(quat,'XYZ');
                    ORI_GT_data = eulZYZ(3);
                    
                    W_ORI_EKF_data = ekf_data.Pose.Pose.Orientation.W;
                    X_ORI_EKF_data = ekf_data.Pose.Pose.Orientation.X;
                    Y_ORI_EKF_data = ekf_data.Pose.Pose.Orientation.Y;
                    Z_ORI_EKF_data = ekf_data.Pose.Pose.Orientation.Z;
                    quat = [W_ORI_EKF_data X_ORI_EKF_data Y_ORI_EKF_data Z_ORI_EKF_data];
                    eulZYZ = quat2eul(quat,'XYZ');
                    ORI_EKF_data = eulZYZ(3);
                    
                    %X_VEL_GT_data = ground_truth_data_vel.Twist.Twist.Linear.X;
                    %Y_VEL_GT_data = ground_truth_data_vel.Twist.Twist.Linear.Y;
                    %X_EKF_data(10,1) = ekf_data.Header.Stamp.Sec;
                    X_EKF_data = ekf_data.Pose.Pose.Position.X;
                    Y_EKF_data = ekf_data.Pose.Pose.Position.Y;
                    X_VEL_EKF_data = ekf_data.Twist.Twist.Linear.X;
                    Y_VEL_EKF_data = ekf_data.Twist.Twist.Linear.Y;
                    Time_EKF_data(4,1) = ekf_data.Header.Stamp.Sec;
                    error_X =  (X_GT_data - X_EKF_data)^2;
                    error_Y =  (Y_GT_data - Y_EKF_data)^2;
                    %error_X_VEL =  (X_VEL_GT_data - X_VEL_EKF_data)^2;
                    %error_Y_VEL =  (Y_VEL_GT_data - Y_VEL_EKF_data)^2;
                    error_pos(4,1) = sqrt(error_X + error_Y);
                    error_ori(4,1) = ORI_GT_data -  ORI_EKF_data;
                    %error_vel(4,1) = sqrt(error_X_VEL + error_Y_VEL);
                    traj_x(10,1) = X_GT_data;
                    traj_y(10,1) = Y_GT_data;
                    plot(app.PosPlot, Time_EKF_data, error_pos, '-r');
                    plot(app.ORIPlot, Time_EKF_data, error_ori, '-b');
                    %plot(app.VelPlot, Time_EKF_data, error_pos, '-r');
                    %plot(app.TrajPlot, traj_x, traj_y, '-b');
                    %error_X(1:9,1) = error_X(2:10,1);
                    %error_Y(1:9,1) = error_Y(2:10,1);
                    error_pos(1:3,1) = error_pos(2:4,1);
                    error_ori(1:3,1) = error_ori(2:4,1);
                    %error_vel(1:3,1) = error_vel(2:4,1);
                    traj_x(1:9,1) = traj_x(2:10,1);
                    traj_y(1:9,1) = traj_y(2:10,1);
                    Time_EKF_data(1:3,1) = Time_EKF_data(2:4,1);
                    if app.logging == 1
                        raw_data = [ground_truth_data ekf_data];
                        app.save_data = vertcat(app.save_data, raw_data);
                        results = [error_pos(4,1) error_ori(4,1) traj_x(10,1) traj_y(10,1)];
                        app.save_results = vertcat(app.save_results, results);
                    end
                    pause(0.5);
                elseif app.Pos == 2
                    app.ORIPlot.Visible = 'off';
                    sub_ground_truth = rossubscriber('/pose');
                    sub_infra_camera = rossubscriber('/pose_traffic');
                    ground_truth_data = receive(sub_ground_truth);
                    infra_camera_data = receive(sub_infra_camera);
                    %X_GT_data = ground_truth_data.Header.Stamp.Sec;
                    X_GT_data = ground_truth_data.Pose.Position.X;
                    Y_GT_data = ground_truth_data.Pose.Position.Y;
                    %X_EKF_data(10,1) = ekf_data.Header.Stamp.Sec;
                    X_INFRA_data = infra_camera_data.Pose.Pose.Position.X;
                    Y_INFRA_data = infra_camera_data.Pose.Pose.Position.Y;
                    Time_INFRA_data(4,1) = infra_camera_data.Header.Stamp.Sec;
                    error_X(4,1) =  (X_GT_data - X_INFRA_data)^2;
                    error_Y(4,1) =  (Y_GT_data - Y_INFRA_data)^2;
                    error_pos(4,1) = sqrt(error_X(4,1) + error_Y(4,1));
                    plot(app.PosPlot, Time_INFRA_data, error_pos, '-r');
                    %plot(app.ORIPlot, Time_EKF_data, error_Y, '-b');
                    %error_X(1:9,1) = error_X(2:10,1);
                    %error_Y(1:9,1) = error_Y(2:10,1);
                    error_pos(1:3,1) = error_pos(2:4,1);
                    Time_INFRA_data(1:3,1) = Time_INFRA_data(2:4,1);
                    if app.logging == 1
                        raw_data = [ground_truth_data infra_camera_data];
                        app.save_data = vertcat(app.save_data, raw_data);
                        results = [error_pos(4,1)];
                        app.save_results = vertcat(app.save_results, results);
                    end
                    pause(0.5); 
                else
                    app.ORIPlot.Visible = 'off';
                    sub_ground_truth = rossubscriber('/DummyCar/pose');
                    sub_infra_camera = rossubscriber('/three_dim_point_world');
                    ground_truth_data = receive(sub_ground_truth);
                    infra_camera_data = receive(sub_infra_camera);
                    Time_INFRA_data(4,1) = ground_truth_data.Header.Stamp.Sec;
                    X_GT_data = ground_truth_data.Pose.Position.X;
                    Y_GT_data = ground_truth_data.Pose.Position.Y;
                    %X_EKF_data(10,1) = ekf_data.Header.Stamp.Sec;
                    X_INFRA_data = infra_camera_data.Point.X;
                    Y_INFRA_data = infra_camera_data.Point.Y;
                    %Time_INFRA_data(4,1) = infra_camera_data.Header.Stamp.Sec;
                    error_X(4,1) =  (X_GT_data - X_INFRA_data)^2;
                    error_Y(4,1) =  (Y_GT_data - Y_INFRA_data)^2;
                    error_pos(4,1) = sqrt(error_X(4,1) + error_Y(4,1));
                    plot(app.PosPlot, Time_INFRA_data, error_pos, '-r');
                    %plot(app.ORIPlot, Time_EKF_data, error_Y, '-b');
                    %error_X(1:9,1) = error_X(2:10,1);
                    %error_Y(1:9,1) = error_Y(2:10,1);
                    error_pos(1:3,1) = error_pos(2:4,1);
                    Time_INFRA_data(1:3,1) = Time_INFRA_data(2:4,1);
                    if app.logging == 1
                        raw_data = [ground_truth_data infra_camera_data];
                        app.save_data = vertcat(app.save_data, raw_data);
                        results = [error_pos(4,1)];
                        app.save_results = vertcat(app.save_results, results);
                    end
                    pause(0.5); 
                end
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.running = 0;
            rosshutdown;
            app.Connect.Color = [1 0 0];
            app.RosTopicsListBox.Items = {};
            
            app.ROSMASTERURIEditField.Enable = 'on';
            app.ConnectButton.Text = 'Connect';
            app.LoggingOnButton.Enable = 'off';
            app.LoggingOffButton.Enable = 'off';
            app.LoggingLamp.Color = [1 0 0];
            cla(app.PosPlot)
            cla(app.ORIPlot)
        end

        % Value changed function: RosTopicsListBox
        function RosTopicsListBoxValueChanged(app, event)

        end

        % Callback function
        function XPlotButtonDown(app, event)
        
        end

        % Button pushed function: LoggingOnButton
        function LoggingOnButtonPushed(app, event)
            app.logging = 1;
            app.LoggingLamp.Color = [0 1 0];
            app.LoggingOnButton.Text = 'Logging';
            app.LoggingOnButton.Enable = 'off';
            app.LoggingOffButton.Enable = 'on';
           
        end

        % Button pushed function: LoggingOffButton
        function LoggingOffButtonPushed(app, event)
            %save('trial_data.mat','app.save_results')
            save_results = app.save_results;
            save_data = app.save_data;
            save('results.mat','save_results')
            save('raw_data.mat','save_data')
            app.logging = 0;
            app.LoggingLamp.Color = [1 0 0];
            app.LoggingOnButton.Text = 'Logging On';
            app.LoggingOnButton.Enable = 'on';
            app.LoggingOffButton.Enable = 'off';
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.RosTopicsListBox.Items = {};
            func(app);
            cla(app.PosPlot)
            cla(app.ORIPlot)
        end

        % Callback function
        function PositionErrorButtonGroupSelectionChanged(app, event)
           
        end

        % Callback function
        function PositionErrorButtonGroupButtonDown(app, event)

        end

        % Value changed function: PositionErrorDropDown
        function PositionErrorDropDownValueChanged(app, event)
            cla(app.PosPlot)
            cla(app.ORIPlot)
            app.PositionErrorDropDown.ItemsData = [1 2 3];
            value = app.PositionErrorDropDown.Value;
            %disp(value)
            if value == 2
                app.Pos = 2;
            elseif value == 3
                app.Pos = 3;
            else
                app.Pos = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PosPlot = uiaxes(app.UIFigure);
            title(app.PosPlot, 'Position Error')
            xlabel(app.PosPlot, 'Simulation Time (s)')
            ylabel(app.PosPlot, 'Error Position (m)')
            zlabel(app.PosPlot, 'Z')
            app.ORIPlot = uiaxes(app.UIFigure);
            title(app.ORIPlot, 'Orientation Error')
            xlabel(app.ORIPlot, 'Simulation Time (s)')
            ylabel(app.ORIPlot, 'Error Orientation(rad)')
            zlabel(app.ORIPlot, 'Z')
            app.ROSMASTERURIEditFieldLabel = uilabel(app.UIFigure);
            app.ROSMASTERURIEditFieldLabel.Text = 'ROS MASTER URI';
            app.ROSMASTERURIEditField = uieditfield(app.UIFigure, 'text');
            app.ROSMASTERURIEditField.ValueChangedFcn = createCallbackFcn(app, @ROSMASTERURIEditFieldValueChanged, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Connect = uilamp(app.UIFigure);
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.RosTopicsListBoxLabel = uilabel(app.UIFigure);
            app.RosTopicsListBoxLabel.Text = 'Ros Topics';
            app.RosTopicsListBox = uilistbox(app.UIFigure);
            app.RosTopicsListBox.Items = {};
            app.RosTopicsListBox.ValueChangedFcn = createCallbackFcn(app, @RosTopicsListBoxValueChanged, true);
            app.RosTopicsListBox.Value = {};
            app.LoggingOnButton = uibutton(app.UIFigure, 'push');
            app.LoggingOnButton.ButtonPushedFcn = createCallbackFcn(app, @LoggingOnButtonPushed, true);
            app.LoggingOnButton.Enable = 'off';
            app.LoggingOnButton.Text = 'Logging On';
            app.LoggingLamp = uilamp(app.UIFigure);
            app.LoggingOffButton = uibutton(app.UIFigure, 'push');
            app.LoggingOffButton.ButtonPushedFcn = createCallbackFcn(app, @LoggingOffButtonPushed, true);
            app.LoggingOffButton.Enable = 'off';
            app.LoggingOffButton.Text = 'Logging Off';
            app.LoggingStatusLabel = uilabel(app.UIFigure);
            app.LoggingStatusLabel.Text = 'Logging Status';
            app.ConnectionStatusLabel = uilabel(app.UIFigure);
            app.ConnectionStatusLabel.Text = 'Connection Status';
            app.RosNodesListBoxLabel = uilabel(app.UIFigure);
            app.RosNodesListBoxLabel.Text = 'Ros Nodes';
            app.RosNodesListBox = uilistbox(app.UIFigure);
            app.RosNodesListBox.Items = {};
            app.RosNodesListBox.Value = {};
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PositionErrorDropDownLabel = uilabel(app.UIFigure);
            app.PositionErrorDropDownLabel.Text = 'Position Error';
            app.PositionErrorDropDown = uidropdown(app.UIFigure);
            app.PositionErrorDropDown.Items = {'EKF (Auto Car)', 'Infra Camera (Auto Car)', 'Infra Camera (Non Auto Car)'};
            app.PositionErrorDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionErrorDropDownValueChanged, true);
            app.PositionErrorDropDown.Value = 'EKF (Auto Car)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end